classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        AbdulJabberAntorID1906062Label  matlab.ui.control.Label
        guide                           matlab.ui.control.EditField
        WELCOMEtoDigitalLogicCircuitImplementationProjectLabel  matlab.ui.control.Label
        GenerateTruthTableButton        matlab.ui.control.Button
        N                               matlab.ui.control.NumericEditField
        TheNumberofLogicInputsLabel     matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ViewTable                       matlab.ui.container.Tab
        TheprogramwillasklogicaloutputvaluesforallLabel  matlab.ui.control.Label
        UpdateTableandViewOutputExpressionButton  matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        OutputForEditField              matlab.ui.control.EditField
        OutputForEditFieldLabel         matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        OutputExpressionTab             matlab.ui.container.Tab
        BackButton                      matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        GenerateOutputExpressionButton  matlab.ui.control.Button
        OutputExp                       matlab.ui.control.TextArea
        LogicCircuitTab                 matlab.ui.container.Tab
        plotguide                       matlab.ui.control.EditField
        BacktoHomeButton                matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        GenerateLogicCircuitButton      matlab.ui.control.Button
        graph                           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        n           %number of inputs
        B=1         %Button click counter
        m           %Matrix for truth table
        inpDone=0;  %Checks if the input is correct or not
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateTruthTableButton
        function GenerateTruthTableButtonPushed(app, event)
            app.B=1; %initialize Button Counter

            %Asking for first input for all possible combinations
            app.OutputForEditField.Value =  num2str(zeros(1,app.n));
            
            %Initializes for next use
            app.OutputExp.Value= " ";
            app.plotguide.Value= " ";
            hold(app.graph,'off')
            plot(app.graph,1,1);
            hold(app.graph,'off')
            app.UITable.Data=0;
            
            %Reads the number of inputs
            app.n=app.N.Value;

            %Generating truth table
            if app.n>=2 && fix(app.n)-app.n == 0
            rows=2^app.n;
            cols=app.n;
            app.m=ones(rows,cols);
                for i = 1:cols
                    j=1;
                    while j<=rows
                        app.m(j,i)=0;
                            if mod(j,2^(cols-i))==0
                                j=j+2^(cols-i);
                            end
                        j=j+1;
                    end
                end

            %Adding the column for output                
            app.m = [app.m zeros(rows,1)];

            %loading the matrix in GUI
            app.UITable.Data=app.m;

            %Naming the columns
            for i=1:cols
                app.UITable.ColumnName{i}=char(64+i);
            end
            app.UITable.ColumnName{i+1}="Output";


            %Asking for input for all possible combinations
            app.OutputForEditField.Value = num2str(zeros(1,cols));

            %Guide for user
            app.guide.Value="The truth table is generated. Please " + ...
                "click on ""View Table"" tab to see the truth table";

            %Goes to different screen
            app.TabGroup.SelectedTab = app.ViewTable;

            %input taken properly
            app.inpDone=1;


            else    %if input is not taken properly
                app.guide.Value= "The input must be an integer" + ...
                    " greater than one";
            end


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
         
           if app.inpDone 
            

                %If taking input is complete,
                % then button press will have no effect
                if app.B~=(2^app.n)+1 
                        app.m(app.B,end)=0;
                end


                %If taking input is complete,
                % then button press will not change the input message
                if app.B<2^(app.n)
                        app.OutputForEditField.Value = num2str(app.m(app.B+1,1:end-1));
                end


                %Taking inputs for button press
                if  app.B<=2^(app.n)
                    app.B=app.B+1;
                    app.UITable.Data=app.m;
                end


            else
                app.TabGroup.SelectedTab = app.HomeTab;
            end
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.inpDone 


                %This button works same as the previous
                if app.B~=(2^app.n)+1
                    app.m(app.B,end)=1;
                end
                if app.B<2^(app.n)
                    app.OutputForEditField.Value = num2str(app.m(app.B+1,1:end-1));
                end
                if  app.B<=2^(app.n)
                    app.B=app.B+1;
                    app.UITable.Data=app.m;
                end
            else
                app.TabGroup.SelectedTab = app.HomeTab;
            end
        end

        % Button pushed function: GenerateOutputExpressionButton
        function GenerateOutputExpressionButtonPushed(app, event)
            if app.inpDone


            %Printing output expression for valid 
            %input by SUM OF PRODUCS rule
            k=0;
            str=' ';
            for i=1:2^app.n
    
                if app.m(i,end)==1
                    if k==1
                        str =[str ' + '];
                    end
                for j = 1:app.n
                    if app.m(i,j)==0
                
                        str = [ str '(~'];
                        str = [ str char(64+j) ];
                        str = [ str ')'];
                
                    elseif app.m(i,j)==1
                
                        str=[ str char(64+j)];
                    end
                    k=1;
                end   
                end
            end


            %special case when all outputs are zeros or ones           
                if strcmp(str,' ')
                    app.OutputExp.Value='0';
                elseif sum(app.m(:,end))==2^(app.n)
                    app.OutputExp.Value='1';
                else
                    app.OutputExp.Value= str;
                end


            else
                app.TabGroup.SelectedTab = app.HomeTab;
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            
            %This button initializes everything
            app.inpDone=0;
            app.B=1;
            app.m(:,end)=0;
            app.UITable.Data=app.m;
            app.OutputForEditField.Value =  num2str(zeros(1,app.n));
            app.TabGroup.SelectedTab = app.HomeTab;
            app.OutputExp.Value= " ";
            app.plotguide.Value= " ";
            hold(app.graph,'off')
            plot(app.graph,1,1);
            hold(app.graph,'off')
            app.UITable.Data=0;
            app.guide.Value= "First input the number of logical inputs and click the button";
        end

        % Button pushed function: GenerateLogicCircuitButton
        function GenerateLogicCircuitButtonPushed(app, event)
if app.inpDone %This button will draw circuit for valid input


    %Special case when all outputs are zeros
    if sum(app.m(:,end))==0

        x=(0:1:8);
        y=ones(1,length(x));
        plot(app.graph,x,y,'m');
        xlim(app.graph,[-1, 9]);
        ylim(app.graph,[0, 1.2]);
        legend(app.graph,"0");
        app.plotguide.Value="The output is 0"; %Message to user


    %Special case when all outputs are zeros
    elseif sum(app.m(:,end))== 2^(app.N.Value)
        x=(0:1:8);
        y=ones(1,length(x));
        plot(app.graph,x,y,'m');  
        xlim(app.graph,[-1, 9]);
        ylim(app.graph,[0, 1.2]);
        legend(app.graph,"1");
        app.plotguide.Value="The output is 1"; %Message to user


    %Other than special cases    
    else
        n=app.N.Value;
        plotg= 'Inputs are ';

        %Message to user
        for i=1:n
            plotg=[plotg char(64+i) ' '];
        end
        plotg=[plotg 'serially.'];
        app.plotguide.Value= plotg; 


        t=sum(app.m(:,end)); %Counting how many ones in output
        xp=0:36;
        yp=-2:0.1:2;
        k=1;
        a=[];


        %This loop creates vectors to plot 
        %Connections between input lines and their complement lines
        for i=1:2^n  
            if app.m(i,end)==1
                for j = 1:n
                    if app.m(i,j)==0
                        start=-17.5+(j-1)*5;
                    elseif app.m(i,j)==1
                        start=-20+(j-1)*5;
                    end
                    a(k,:)= linspace(start,2*n+2,3);
                    k=k+1;
                end
            end         
        end


        %This loops draws input lines and there complements
        for i=1:n
            y1=15:-1:-t*32;
            x1=i*ones(1,length(y1));
            plot(app.graph,5*x1-25,y1,'r');
            legend(app.graph,'off');
            axis([-30 80 -80 30])
            xlim(app.graph,[-30, 80]);
            ylim(app.graph,[-80, 30]);
            hold(app.graph,'on')
            y2=6.6:-1:-t*32 ;
            x2=i*ones(1,length(y2))+0.5;
            plot(app.graph,5*x2-25,y2,'m');
            y3=[10:-1:8] ;
            x3=i*ones(1,length(y3))+0.5;
            plot(app.graph,5*x3-25,y3,'r');
            x4=i+0.25:0.05:i+0.25+0.5;
            y4=4*abs(x4-i-0.5)+7;
            plot(app.graph,5*x4-25,y4,'b');
            r=0.2;
            th = 0:0.1:2*pi;
            xunit = r * cos(th);
            yunit = r * sin(th);
            plot(app.graph,xunit+((5*x4(1)-25)+(5*x4(end)-25))/2, yunit+y4(1)-1.2,'b');
            yh=y3(end)*ones(1,length(x4));
            plot(app.graph,5*x4-25,yh,'b');
            x5=i:0.1:i+0.5;
            y5=y3(1)*ones(1,length(x5));
            plot(app.graph,5*x5-25,y5,'r');
        end

        k=1;


        %this loop draws AND gates and their connection lines
        for i=0:t-1
            y6=(0:-1:-8);
            x6=(2*n)*ones(1,length(y6))+2;
            plot(app.graph,x6,y6-i*32,'b');    
            plot(app.graph,a(k,:),zeros(length(a(k,:)))+y6(1)-i*32,'m')
            k=k+1;
            plot(app.graph,a(k,:),zeros(length(a(k,:)))+y6(end)-i*32,'m')
            k=k+1;
            yc=-4:0.1:4;
            xc=sqrt(16-(yc).^2)+x6(1);
            plot(app.graph,xc,yc+(y6(1)+y6(end))/2 - i*32,'b')
            plot(app.graph,xc-4,zeros(1,length(xc))- i*32,'m')
            plot(app.graph,xc-4,zeros(1,length(xc))- i*32-8,'m')

                %Draws additonal AND gates if needed
                for j=1:n-2
                    plot(app.graph,x6+j*8,y6-i*32-j*4,'b');
                    plot(app.graph,a(k,:),zeros(length(a(k,:)))+y6(end)-i*32-j*4,'m')
                    k=k+1;
                    plot(app.graph,xc+j*8,yc+(y6(1)+y6(end))/2 - i*32-j*4,'b')
                    plot(app.graph,xc+j*8,yc+(y6(1)+y6(end))/2 - i*32-j*4,'b')
                    plot(app.graph,xc-4+j*8,zeros(1,length(xc))- i*32-j*4,'m')
                    plot(app.graph,linspace(0,x6(1)+j*8,5),zeros(1,5)- i*32-8-j*4,'m')
                end
        end


        %This loop draws OR gates and their connection lines
        for i=0:t-2
            for k=1:t-1   
                xp1=-yp.^2;
                xp2=-0.1*yp.^2-3.6;
                plot(app.graph,xp1+(n-2)*10+10+(i+1)*40,2*yp-4*i-8-(n-2)*4,'b')
                plot(app.graph,xp2+(n-2)*10+10+(i+1)*40,2*yp-4*i-8-(n-2)*4,'b')
                plot(app.graph,yp+(n-2)*10+44+40*(k-1),zeros(1,length(yp))- k*4-8 -(n-2)*4,'m')
                plot(app.graph,xp+(n-2)*10+10+40*(k-1),zeros(1,length(xp))- k*4-0-(n-2)*4,'m')
                s=xp(1)+(n-2)*10+10;
                f=yp(1)+(n-2)*10+44+40*(k-1);
                xpp=s:f;
                plot(app.graph,xpp,zeros(1,length(xpp))- (k-1)*(32)-36-(n-2)*4,'m')
                yh = (-(k-1)*(32)-36-(n-2)*4):(- k*4-8-(n-2)*4);
                plot(app.graph,zeros(1,length(yh))+xpp(end),yh,'m')
            end
        end
    
        plot(app.graph,xp+(n-2)*10+10+40*(t-1),zeros(1,length(xp))- t*4-0-(n-2)*4,'m')
        close(1);
    end
else
     app.TabGroup.SelectedTab = app.HomeTab; %if input is not valid
end
        end

        % Button pushed function: UpdateTableandViewOutputExpressionButton
        function UpdateTableandViewOutputExpressionButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.OutputExpressionTab;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
             app.TabGroup.SelectedTab =app.LogicCircuitTab;
        end

        % Button pushed function: BacktoHomeButton
        function BacktoHomeButtonPushed(app, event)
            app.TabGroup.SelectedTab =app.HomeTab;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.TabGroup.SelectedTab =app.ViewTable;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = 'Circuit Board 590.jpg';
            app.TheNumberofLogicInputsLabel = uilabel(app.HomeTab);
            app.TheNumberofLogicInputsLabel.Text = 'The Number of Logic Inputs';
            app.N = uieditfield(app.HomeTab, 'numeric');
            app.N.Value = 2;
            app.GenerateTruthTableButton = uibutton(app.HomeTab, 'push');
            app.GenerateTruthTableButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTruthTableButtonPushed, true);
            app.GenerateTruthTableButton.Text = 'Generate Truth Table';
            app.WELCOMEtoDigitalLogicCircuitImplementationProjectLabel = uilabel(app.HomeTab);
            app.WELCOMEtoDigitalLogicCircuitImplementationProjectLabel.Text = {'WELCOME '; 'to '; 'Digital Logic Circuit Implementation'; 'Project'};
            app.guide = uieditfield(app.HomeTab, 'text');
            app.guide.Value = 'First input the number of logical inputs and click the button  ';
            app.AbdulJabberAntorID1906062Label = uilabel(app.HomeTab);
            app.AbdulJabberAntorID1906062Label.Text = {'Abdul Jabber Antor'; 'ID: 1906062'};
            app.ViewTable = uitab(app.TabGroup);
            app.ViewTable.Title = 'View  Table';
            app.UITable = uitable(app.ViewTable);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.OutputForEditFieldLabel = uilabel(app.ViewTable);
            app.OutputForEditFieldLabel.Text = 'Output For';
            app.OutputForEditField = uieditfield(app.ViewTable, 'text');
            app.OutputForEditField.Editable = 'off';
            app.Label = uilabel(app.ViewTable);
            app.Label.Text = '=';
            app.Button_2 = uibutton(app.ViewTable, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '0';
            app.Button_3 = uibutton(app.ViewTable, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '1';
            app.ResetButton = uibutton(app.ViewTable, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UpdateTableandViewOutputExpressionButton = uibutton(app.ViewTable, 'push');
            app.UpdateTableandViewOutputExpressionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableandViewOutputExpressionButtonPushed, true);
            app.UpdateTableandViewOutputExpressionButton.Text = {'Update Table'; 'and'; 'View Output Expression'};
            app.TheprogramwillasklogicaloutputvaluesforallLabel = uilabel(app.ViewTable);
            app.TheprogramwillasklogicaloutputvaluesforallLabel.Text = {'The program will ask logical '; 'output values  for all'; 'possible combinations of '; 'input values.  Please enter the'; 'values for input and'; 'click the button below.  '; 'If you want to change the '; 'values, then click '; 'on Reset Button.'};
            app.OutputExpressionTab = uitab(app.TabGroup);
            app.OutputExpressionTab.Title = 'Output Expression';
            app.OutputExp = uitextarea(app.OutputExpressionTab);
            app.GenerateOutputExpressionButton = uibutton(app.OutputExpressionTab, 'push');
            app.GenerateOutputExpressionButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateOutputExpressionButtonPushed, true);
            app.GenerateOutputExpressionButton.Text = 'Generate Output Expression';
            app.NextButton = uibutton(app.OutputExpressionTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next >>';
            app.BackButton = uibutton(app.OutputExpressionTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '<<Back';
            app.LogicCircuitTab = uitab(app.TabGroup);
            app.LogicCircuitTab.Title = 'Logic Circuit';
            app.graph = uiaxes(app.LogicCircuitTab);
            app.GenerateLogicCircuitButton = uibutton(app.LogicCircuitTab, 'push');
            app.GenerateLogicCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateLogicCircuitButtonPushed, true);
            app.GenerateLogicCircuitButton.Text = {'Generate'; 'Logic'; 'Circuit'};
            app.Label_2 = uilabel(app.LogicCircuitTab);
            app.Label_2.Text = {''; ''; 'This program uses'; 'SUM OF PRODUCTS rule '; ' to generate output '; 'expression and logic circuit.'};
            app.BacktoHomeButton = uibutton(app.LogicCircuitTab, 'push');
            app.BacktoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoHomeButtonPushed, true);
            app.BacktoHomeButton.Text = {'Back to'; 'Home'};
            app.plotguide = uieditfield(app.LogicCircuitTab, 'text');
            app.plotguide.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end